classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        StartButton                     matlab.ui.control.Button
        SelectSensorTypeDropDownLabel   matlab.ui.control.Label
        SelectSensorTypeDropDown        matlab.ui.control.DropDown
        SelectInputPinButtonGroup       matlab.ui.container.ButtonGroup
        A0Button                        matlab.ui.control.RadioButton
        A1Button                        matlab.ui.control.RadioButton
        A2Button                        matlab.ui.control.RadioButton
        A3Button                        matlab.ui.control.RadioButton
        A4Button                        matlab.ui.control.RadioButton
        A5Button                        matlab.ui.control.RadioButton
        PinoutButton                    matlab.ui.control.Button
        OperatingVoltageEditFieldLabel  matlab.ui.control.Label
        OperatingVoltageEditField       matlab.ui.control.NumericEditField
        MinsensorreadingEditFieldLabel  matlab.ui.control.Label
        MinsensorreadingEditField       matlab.ui.control.NumericEditField
        MaxsensorreadingEditFieldLabel  matlab.ui.control.Label
        MaxsensorreadingEditField       matlab.ui.control.NumericEditField
        NamethesensorEditFieldLabel     matlab.ui.control.Label
        NamethesensorEditField          matlab.ui.control.EditField
        UnitsEditFieldLabel             matlab.ui.control.Label
        UnitsEditField                  matlab.ui.control.EditField
        HelpTab                         matlab.ui.container.Tab
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% Acquire and analyze data from a temperature sensor
            
            %% Connect to Arduino
            % Use the arduino command to connect to an Arduino device.
            
            f = uifigure;
            
            if(app.OperatingVoltageEditField.Value>5)
                uialert(f,'The Operating voltage is higher than 5V. Plugging it directly into the arduino may cause damage','Input voltage warning');
            end
            
            a = arduino;
            
            %% Take a single temperature measurement
            % The datasheet for the TMP36 temperature sensor tells us that the voltage
            % reading is directly proportional to temperature in Celsius with an 
            % offset of 0.5V and a scale factor of 10 mV/°C (equivalent to 100 °C/V).
            % Therefore the conversion can be represented as
            %
            % $T_C = (V-0.5)*100$
            %
            % We can read the output voltage, convert it to Celsius and convert the
            % result to Farenheit as follows:
            
%             if(app.A0Button.Value)
%                 input = 'A0';
%             elseif(app.A1Button.Value)
%                 input = 'A1';
%             elseif(app.A2Button.Value)
%                 input = 'A2'; 
%             elseif(app.A3Button.Value)
%                 input = 'A3';
%             elseif(app.A4Button.Value)
%                 input = 'A4'; 
%             elseif(app.A5Button.Value)
%                 input = 'A0';    
%             end
%             
%             
%          
%             v = readVoltage(a,input)
%             TempC = (v - 0.5)*100;
%             TempF = 9/5*TempC + 32;
%             fprintf('Temperature Reading:\n  %.1f °C\n  %.1f °F\n',TempC,TempF)
%             
%             %% Record and plot 10 seconds of temperature data
%            
%             ii = 0;
%             TempF = zeros(1e4,1);
%             t = zeros(1e4,1);
%             
%             tic
%             while toc < 10
%                 ii = ii + 1;
%                 % Read current voltage value
%                 v = readVoltage(a,input);
%                 
%                 
%                 
%                 % Calculate temperature from voltage (based on data sheet)
%                 TempC = (v - 0.5)*100;
%                 TempF(ii) = 9/5*TempC + 32;
%                 % Get time since starting
%                 t(ii) = toc;
%             end
%             
%             % Post-process and plot the data. First remove any excess zeros on the
%             % logging variables.
%             TempF = TempF(1:ii);
%             t = t(1:ii);
%             % Plot temperature versus time
%             figure
%             plot(t,TempF,'-o')
%             xlabel('Elapsed time (sec)')
%             ylabel('Temperature (\circF)')
%             title('Ten Seconds of Temperature Data')
%             set(gca,'xlim',[t(1) t(ii)])
%             
%             %% Compute acquisition rate
%             
%             timeBetweenDataPoints = diff(t);
%             averageTimePerDataPoint = mean(timeBetweenDataPoints);
%             dataRateHz = 1/averageTimePerDataPoint;
%             fprintf('Acquired one data point per %.3f seconds (%.f Hz)\n',...
%                 averageTimePerDataPoint,dataRateHz)
%             
%             %% Why is my data so choppy?
%             
%             measurableIncrementV = 5/1023;
%             measurableIncrementC = measurableIncrementV*100;
%             measurableIncrementF = measurableIncrementC*9/5;
%             fprintf('The smallest measurable increment of this sensor by the Arduino is\n %-6.4f V\n %-6.2f°C\n %-6.2f°F\n',...
%                 measurableIncrementV,measurableIncrementC,measurableIncrementF);
%             
%             %% Acquire and display live data
            %maxOperating = app.OperatingVoltageEditField.Value;
            maxOperating = app.MaxsensorreadingEditField.Value;
            minOperating = app.MinsensorreadingEditField.Value;
            figure
            h = animatedline;
            ax = gca;
            ax.YGrid = 'on';
            ax.YLim = [minOperating maxOperating];
            %set(ax,'YTick', 0:maxOperating/1023:maxOperating)
            
            stop = false;
            startTime = datetime('now');
            while ~stop
                
                if(app.A0Button.Value)
                    input = 'A0';
                elseif(app.A1Button.Value)
                    input = 'A1';
                elseif(app.A2Button.Value)
                    input = 'A2'; 
                elseif(app.A3Button.Value)
                    input = 'A3';
                elseif(app.A4Button.Value)
                    input = 'A4'; 
                elseif(app.A5Button.Value)
                    input = 'A0';    
                end
                
                % Read current voltage value
                v = readVoltage(a,input);
                
                OldMax = 5;
                OldMin = 0;
                OldValue = v;
                
                NewMax = app.MaxsensorreadingEditField.Value;
                NewMin = app.MinsensorreadingEditField.Value;
                
                OldRange = (OldMax - OldMin);
                NewRange = (NewMax - NewMin); 
                NewValue = (((OldValue - OldMin) * NewRange) / OldRange) + NewMin ;
                
                
                % Calculate temperature from voltage (based on data sheet)
                %TempC = (v - 0.5)*100;
                TempC = NewValue;
                TempF = 9/5*TempC + 32;    
                % Get current time
                t =  datetime('now') - startTime;
                % Add points to animation
                addpoints(h,datenum(t),TempC)
                % Update axes
                ax.XLim = datenum([t-seconds(15) t]);
                datetick('x','keeplimits')
                drawnow
                % Check stop condition
                stop = readDigitalPin(a,'D12');
            end
            
            % Input sensor limits
            
            max = app.MaxsensorreadingEditField.Value;
            min = app.MinsensorreadingEditField.Value;
            
            %% Plot the recorded data
            
            [timeLogs,tempLogs] = getpoints(h);
            timeSecs = (timeLogs-timeLogs(1))*24*3600;
            figure
            plot(timeSecs,tempLogs)
            
            last = length(timeSecs);
            
            axis([0 timeSecs(last) min max]);
            xlabel('Elapsed time (sec)')
          
            if strcmp(app.SelectSensorTypeDropDown.Value , 'Temperature')
                ylabel('Temperature (\circC)')
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Infrared')
                ylabel(strcat('Frequency ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Ultrasonic')
                ylabel(strcat('Frequency ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Other')
                ylabel(app.UnitsEditField.Value)    
                 
            end
            
            
            %% Smooth out readings with moving average filter
            
            smoothedTemp = smooth(tempLogs,25);
            tempMax = smoothedTemp + 2*9/5;
            tempMin = smoothedTemp - 2*9/5;
            
            figure
            plot(timeSecs,tempLogs, timeSecs,tempMax,'r--',timeSecs,tempMin,'r--')
            xlabel('Elapsed time (sec)')
            if strcmp(app.SelectSensorTypeDropDown.Value , 'Temperature')
                ylabel('Temperature (\circC)')
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Infrared')
                ylabel(strcat('Frequency ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Ultrasonic')
                ylabel(strcat('Frequency ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Other')
                ylabel(app.UnitsEditField.Value)    
                 
            end
            hold on 
            
            %%
            % Plot the original and the smoothed temperature signal, and illustrate the
            % uncertainty.
            
            plot(timeSecs,smoothedTemp,'r')
            axis([0 timeSecs(last) min max]);
            %% Save results to a file

            T = table(timeSecs',tempLogs','VariableNames',{'Time_sec','Reading'});
            selpath = uigetdir;
            filename = 'Sensor_Data.xlsx';
            fileWrite = strcat(selpath,filename);
            % Write table to file 
            writetable(T,fileWrite)
            % Print confirmation to command line
            fprintf('Results table with %g temperature measurements saved to file %s\n',...
                length(timeSecs),filename)
                
        end

        % Button pushed function: PinoutButton
        function PinoutButtonPushed(app, event)
            arduinoPinout = imread('arduinoPinout.jpg');
            imshow(arduinoPinout);
        end

        % Value changed function: SelectSensorTypeDropDown
        function SelectSensorTypeDropDownValueChanged(app, event)
            Value = true;
            
            sensorType = app.SelectSensorTypeDropDown.Value;
            
            if strcmp(sensorType , 'Other')
                app.NamethesensorEditField.Visible = 'on';
                app.UnitsEditField.Visible = 'on';
            elseif strcmp(sensorType , 'Infrared') 
                app.UnitsEditField.Visible = 'on';
            elseif strcmp(sensorType , 'Ultrasonic') 
                app.UnitsEditField.Visible = 'on';    
            else
                app.NamethesensorEditField.Visible = 'off'; 
                app.UnitsEditField.Visible = 'off';
            end
            
        end

        % Selection changed function: SelectInputPinButtonGroup
        function SelectInputPinButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectInputPinButtonGroup.SelectedObject;
        end

        % Callback function
        function StopButtonPushed(app, event)

        end

        % Value changed function: OperatingVoltageEditField
        function OperatingVoltageEditFieldValueChanged(app, event)
            value = app.OperatingVoltageEditField.Value;
    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.StartButton = uibutton(app.MainTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectSensorTypeDropDownLabel = uilabel(app.MainTab);
            app.SelectSensorTypeDropDownLabel.Text = 'Select Sensor Type';
            app.SelectSensorTypeDropDown = uidropdown(app.MainTab);
            app.SelectSensorTypeDropDown.Items = {'Temperature', 'Infrared', 'Ultrasonic', 'Other'};
            app.SelectSensorTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSensorTypeDropDownValueChanged, true);
            app.SelectSensorTypeDropDown.Value = 'Temperature';
            app.SelectInputPinButtonGroup = uibuttongroup(app.MainTab);
            app.SelectInputPinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectInputPinButtonGroupSelectionChanged, true);
            app.SelectInputPinButtonGroup.Title = 'Select Input Pin';
            app.A0Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A0Button.Text = 'A0';
            app.A0Button.Value = true;
            app.A1Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A1Button.Text = 'A1';
            app.A2Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A2Button.Text = 'A2';
            app.A3Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A3Button.Text = 'A3';
            app.A4Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A4Button.Text = 'A4';
            app.A5Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A5Button.Text = 'A5';
            app.PinoutButton = uibutton(app.SelectInputPinButtonGroup, 'push');
            app.PinoutButton.ButtonPushedFcn = createCallbackFcn(app, @PinoutButtonPushed, true);
            app.PinoutButton.Text = 'Pinout';
            app.OperatingVoltageEditFieldLabel = uilabel(app.MainTab);
            app.OperatingVoltageEditFieldLabel.Text = 'Operating Voltage';
            app.OperatingVoltageEditField = uieditfield(app.MainTab, 'numeric');
            app.OperatingVoltageEditField.Limits = [0 Inf];
            app.OperatingVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @OperatingVoltageEditFieldValueChanged, true);
            app.MinsensorreadingEditFieldLabel = uilabel(app.MainTab);
            app.MinsensorreadingEditFieldLabel.Text = 'Min sensor reading';
            app.MinsensorreadingEditField = uieditfield(app.MainTab, 'numeric');
            app.MaxsensorreadingEditFieldLabel = uilabel(app.MainTab);
            app.MaxsensorreadingEditFieldLabel.Text = 'Max sensor reading';
            app.MaxsensorreadingEditField = uieditfield(app.MainTab, 'numeric');
            app.NamethesensorEditFieldLabel = uilabel(app.MainTab);
            app.NamethesensorEditFieldLabel.Text = 'Name the sensor';
            app.NamethesensorEditField = uieditfield(app.MainTab, 'text');
            app.UnitsEditFieldLabel = uilabel(app.MainTab);
            app.UnitsEditFieldLabel.Text = 'Units';
            app.UnitsEditField = uieditfield(app.MainTab, 'text');
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end